classdef guiApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RunSimulationButton            matlab.ui.control.Button
        StatusEditFieldLabel           matlab.ui.control.Label
        StatusEditField                matlab.ui.control.EditField
        Lamp                           matlab.ui.control.Lamp
        UIAxes_PowerCurve              matlab.ui.control.UIAxes
        AbortSimulationButton          matlab.ui.control.Button
        NACA0012asReferenceAirfoilPanel  matlab.ui.container.Panel
        CordLengthEditFieldLabel       matlab.ui.control.Label
        CordLengthEditField            matlab.ui.control.NumericEditField
        EnabledCheckBox_airfoil_1      matlab.ui.control.CheckBox
        CustomProfilePanel             matlab.ui.container.Panel
        FilePathEditFieldLabel         matlab.ui.control.Label
        FilePathEditField              matlab.ui.control.EditField
        CordLengthEditField_3Label     matlab.ui.control.Label
        CordLengthEditField_3          matlab.ui.control.NumericEditField
        EnabledCheckBox_3              matlab.ui.control.CheckBox
        LoadProfileButton              matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        Airfoil2Panel                  matlab.ui.container.Panel
        Airfoil1DropDown_2             matlab.ui.control.DropDown
        CordLengthEditField_4Label     matlab.ui.control.Label
        CordLengthEditField_4          matlab.ui.control.NumericEditField
        EnabledCheckBox_airfoil_2      matlab.ui.control.CheckBox
        InputdataPanel                 matlab.ui.container.Panel
        MediumButtonGroup              matlab.ui.container.ButtonGroup
        WaterButton                    matlab.ui.control.RadioButton
        AirButton                      matlab.ui.control.RadioButton
        ReynoldsNumberEditFieldLabel   matlab.ui.control.Label
        ReynoldsNumberEditField        matlab.ui.control.NumericEditField
        CykloneDataPathEditFieldLabel  matlab.ui.control.Label
        ListBoxLabel                   matlab.ui.control.Label
        ListBox                        matlab.ui.control.ListBox
        CykloneDataPathEditField       matlab.ui.control.EditField
        ProfilePowerCurvesComparerLabel  matlab.ui.control.Label
    end


    methods (Access = private)
    
        function runSimulation(app, airfoilCordLength)
            
            disp('Simulation running ...');
            ro = 1000.0;
            AbsoluteWaterSpeedVA = zeros(361,3);
            ReynoldsNumber = app.ReynoldsNumberEditField.Value;
            AbsoluteWaterSpeed = 1;
            AbsoluteWaterSpeedV = AbsoluteWaterSpeed*[0 -1 0];                                            %This vector is arbitrary fixed
            for i=1:1:360                                                               %This loop makes a list of vectors to simplify further calculations
                AbsoluteWaterSpeedVA(i,:) = AbsoluteWaterSpeedV;
            end
            i = 1;
            ChordLenght = airfoilCordLength;
            Height = 1;
            BladesNumber = 3;
            Radius = 1;
            
            for AngularSpeed = 2 : 0.5 : 20
                
                AngularSpeedV = AngularSpeed * [0 0 1];                                       % Transforms the angular speed to a vector for further calculations
                [RelativeWaterSpeedM, PeripheralSpeedVA, RadiusVA, RelativeWaterSpeedVA, AngleOfAttackA, Theta] = vectorcalculator(Radius, AngularSpeedV, AbsoluteWaterSpeedV); %Uses the funtion made to get the values for more info use comand 'help'
                RelativeWaterSpeedM2 = RelativeWaterSpeedM .* RelativeWaterSpeedM;
                DynamicPreasure = 0.5 * ro * RelativeWaterSpeedM2;
                [Power, Moment, TotalForce, TangentialForce, NormalForce, Ct, Cn] = dynamiccalculator(ReynoldsNumber, AngleOfAttackA, RelativeWaterSpeedM2, Height, ChordLenght, Theta, Radius, AngularSpeed);
                Theta = 0 : 1 : 360;
                Theta = deg2rad(Theta);
                
                T =(AngularSpeed * Radius / AbsoluteWaterSpeed);
                P = ((cos(Theta)+T).^2 + sin(Theta).^2);
                Q = (Cn' .* sin(Theta) - Ct' .* cos(Theta));
                J =(P .* Q);
                G = (BladesNumber * ChordLenght)/(16*180*Radius)*trapz(J);
                
                
                k = (1-G)/(1+G);
                TotalPower = Power(:,1)+Power(:,2)+Power(:,3);
                TotalPowerM = (trapz(TotalPower))*2*pi/360.0;
                
                TotalRelativeWaterSpeedM = (trapz(RelativeWaterSpeedM(:,1)))*2*pi/360.0;
                PowerExp = 0.5*ro*Radius*Height*2*(2*AbsoluteWaterSpeed/(1+k))^3;
                Cp = (BladesNumber*ChordLenght)/(32*180*Radius)*trapz(Ct'.*((P*AngularSpeed*Radius/AbsoluteWaterSpeed)*(1+k)^3));
                NewTipSpeed = AngularSpeed*Radius/AbsoluteWaterSpeed*((1+k)/2);
                CpT(i,:)=[NewTipSpeed Cp];
                Checker(i,:)=[G k];
                i=i+1;
            end
            hold(app.UIAxes_PowerCurve);
            plot(app.UIAxes_PowerCurve, CpT(:,1), CpT(:,2));
            
        end
        
        function canSimulationStart = isDataValid(app, cordLength)
            reynoldsNum = app.ReynoldsNumberEditField.Value;
            cyklonePath = app.CykloneDataPathEditField.Value;
            
            canSimulationStart = true;
            
            if (cordLength <= 0 || reynoldsNum <= 0 || cyklonePath == "")
                app.StatusEditField.FontColor = 'red';
                app.StatusEditField.Value = 'Incorrect Data!';
                app.Lamp.Color = 'red';
                
                pause(0.3);
                
                app.StatusEditField.Value = '';
                app.Lamp.Color = '#D3D3D3';
                canSimulationStart = false;
                return;
            end
      
            darkGreenColor = [0.1660 0.5740 0.1880];
            app.StatusEditField.FontColor = darkGreenColor;
            app.StatusEditField.Value = 'Simulation started!';
            app.Lamp.Color = 'green';
            
            pause(0.3);
            app.StatusEditField.Value = '';
            app.Lamp.Color = '#D3D3D3';
            
            canSimulationStart = true;
        end
        
        function validateAirfoil_1(app)
            
            disp('Validation of first airfoil ...');
            
            canSimulationStart = app.isDataValid(app.CordLengthEditField.Value);
            if(canSimulationStart ~= true)
                return;
            end
            
            app.runSimulation(app.CordLengthEditField.Value);
            
            
        end
        
        function validateAirfoil_2(app)
            
            disp('Validation of second airfoil ...');
            
            canSimulationStart = app.isDataValid(app.CordLengthEditField_4.Value);
            if(canSimulationStart ~= true)
                return;
            end
            
            app.runSimulation(app.CordLengthEditField_4.Value);
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function AnalyzeFirstButtonPushed(app, event)
            filename = app.FirstFilenameEditField.Value;
            
            if(filename == "")
                app.StatusEditField.Value = '';
                return;
            end
            
            fileID = fopen(filename);
            if(fileID < 0)
                app.StatusEditField.Value = 'File Not Found!';
                return;
            end
            
            app.parseFile(fileID);
            
        end

        % Callback function
        function AnalyzeSecondButtonPushed(app, event)
            filename = app.SecFilenameEditField.Value;
            
            if(filename == "")
                app.StatusEditField_2.Value = '';
                return;
            end
            
            fileID = fopen(filename);
            if(fileID < 0)
                app.StatusEditField_2.Value = 'File Not Found!';
                return;
            end
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            
            app.UIAxes_PowerCurve.cla;
            pause(2);
            plot(app.UIAxes_PowerCurve , [0.1 0.2 0.3 0.4],[1 2 3 4],'-r');
            hold(app.UIAxes_PowerCurve, "on");
            plot(app.UIAxes_PowerCurve, [1 2 3 4], [10 9 4 7],'--b');
            
            return;
            
            
            if (app.EnabledCheckBox_airfoil_1.Value == true)
                app.validateAirfoil_1();
            end
            if (app.EnabledCheckBox_airfoil_2.Value == true)
                app.validateAirfoil_2();
            end           
            
        end

        % Button pushed function: LoadProfileButton
        function LoadProfileButtonPushed(app, event)
            if (app.EnabledCheckBox_3.Value ~= true)
                return;
            end
            
            if(app.FilePathEditField.Value == "")
                disp('Field is empty');
                return;
            end
            
            % Create filename for the custom profile data
            filename   = app.FilePathEditField.Value;
            profileCoordinatesPath   = sprintf('%s\\Profiles\\%s', pwd ,filename);
            saveToFile = sprintf('%s\\JavafoilData\\%s', pwd, filename);
            disp(profileCoordinatesPath);
            
            % Check if file exists
            fileID = fopen(profileCoordinatesPath);
            if (fileID < 0)
                disp('File does not exist!');
                return;
            end
             
            %% count lines in the file
            nrLines = 0;
            while true
                tline = fgetl(fileID);
                if(tline == -1)
                    frewind(fileID);
                    break;
                end
                nrLines = nrLines + 1;
            end
            nrLines
            %% fill in the vector with file's lines
            lines = strings(nrLines, 1);
            for i = 1: 1: nrLines
                tline = fgetl(fileID);
                
                if(tline == -1)
                    fclose(fileID);
                    break;
                end
                
                if (tline ~= "")
                    lines(i) = tline;
                end
            end
            
            % Parse the file
            coordinates = {};
            cellIndex = 1;
            
            for i = 1:1: length(lines)
                lines(i) = replace(lines(i), ',', '.');
                newStr = split(lines(i));
                if (length(newStr) == 2 && i ~= 1)
                    coordinates(1, cellIndex) = num2cell(str2double(newStr(1)));
                    coordinates(2, cellIndex) = num2cell(str2double(newStr(2)));
                    cellIndex = cellIndex + 1;
                end
            end
            
            % Put data onto the screen
            plotTitle = lines(1);
            plotCoordinates = cell2mat(coordinates);
            xCoord = plotCoordinates(1, :);
            yCoord = plotCoordinates(2, :);
            
            plot(app.UIAxes2, xCoord, yCoord);
            app.UIAxes2.Title.String = plotTitle;
            
            % Create Data Storage File to be used in the script
            fileID = fopen('dataStorageFile.txt', 'w');
            if (fileID < 0)
                disp('Storage file is not present!');
                return;
            end
            
            if (app.WaterButton.Value == true)
                density       = '1000';
                viscosity     = '0.0000013053';
                speedOfSound  = '1447';
            elseif (app.AirButton.Value == true)
                density       = '1.225';
                viscosity     = '0.000014607';
                speedOfSound  = '340';
            end
            
            fprintf(fileID, '%s\n%s\n%s\n%s\n%s', profileCoordinatesPath, density, viscosity, speedOfSound, saveToFile);
            
            mhclassesPath = 'C:\\Program Files (x86)\\MH AeroTools\\JavaFoil\\java\\mhclasses.jar';
            javafoilPath  = 'C:\\Program Files (x86)\\MH AeroTools\\JavaFoil\\java\\javafoil.jar';
            scriptPath    = 'C:\Users\borys\Desktop\GUI_MATLAB\Scripts\loadCustomProfile.jfscript';
            command        = sprintf('java.exe -DScript="%s" -cp "%s" -cp "%s" MH.JavaFoil.JavaFoil &', scriptPath, mhclassesPath, javafoilPath);
            
            system(command, '-echo');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.StatusEditField.Editable = 'off';
            app.Lamp = uilamp(app.UIFigure);
            app.UIAxes_PowerCurve = uiaxes(app.UIFigure);
            title(app.UIAxes_PowerCurve, 'Power Curve')
            xlabel(app.UIAxes_PowerCurve, 'X')
            ylabel(app.UIAxes_PowerCurve, 'Y')
            app.AbortSimulationButton = uibutton(app.UIFigure, 'push');
            app.AbortSimulationButton.Text = 'Abort Simulation';
            app.NACA0012asReferenceAirfoilPanel = uipanel(app.UIFigure);
            app.NACA0012asReferenceAirfoilPanel.Title = 'NACA 0012 as Reference Airfoil';
            app.CordLengthEditFieldLabel = uilabel(app.NACA0012asReferenceAirfoilPanel);
            app.CordLengthEditFieldLabel.Text = 'Cord Length';
            app.CordLengthEditField = uieditfield(app.NACA0012asReferenceAirfoilPanel, 'numeric');
            app.EnabledCheckBox_airfoil_1 = uicheckbox(app.NACA0012asReferenceAirfoilPanel);
            app.EnabledCheckBox_airfoil_1.Text = 'Enabled';
            app.CustomProfilePanel = uipanel(app.UIFigure);
            app.CustomProfilePanel.Title = 'Custom Profile';
            app.FilePathEditFieldLabel = uilabel(app.CustomProfilePanel);
            app.FilePathEditFieldLabel.Text = 'FilePath';
            app.FilePathEditField = uieditfield(app.CustomProfilePanel, 'text');
            app.CordLengthEditField_3Label = uilabel(app.CustomProfilePanel);
            app.CordLengthEditField_3Label.Text = 'Cord Length';
            app.CordLengthEditField_3 = uieditfield(app.CustomProfilePanel, 'numeric');
            app.EnabledCheckBox_3 = uicheckbox(app.CustomProfilePanel);
            app.EnabledCheckBox_3.Text = 'Enabled';
            app.LoadProfileButton = uibutton(app.CustomProfilePanel, 'push');
            app.LoadProfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProfileButtonPushed, true);
            app.LoadProfileButton.Text = 'Load Profile';
            app.UIAxes2 = uiaxes(app.CustomProfilePanel);
            title(app.UIAxes2, 'Custom Profile')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.Airfoil2Panel = uipanel(app.UIFigure);
            app.Airfoil2Panel.Title = 'Airfoil 2';
            app.Airfoil1DropDown_2 = uidropdown(app.Airfoil2Panel);
            app.Airfoil1DropDown_2.Items = {'NACA 0012', 'NACA 0015', 'NACA 0018', 'NACA 2412'};
            app.Airfoil1DropDown_2.Value = 'NACA 0012';
            app.CordLengthEditField_4Label = uilabel(app.Airfoil2Panel);
            app.CordLengthEditField_4Label.Text = 'Cord Length';
            app.CordLengthEditField_4 = uieditfield(app.Airfoil2Panel, 'numeric');
            app.EnabledCheckBox_airfoil_2 = uicheckbox(app.Airfoil2Panel);
            app.EnabledCheckBox_airfoil_2.Text = 'Enabled';
            app.InputdataPanel = uipanel(app.UIFigure);
            app.InputdataPanel.Title = 'Input data';
            app.MediumButtonGroup = uibuttongroup(app.InputdataPanel);
            app.MediumButtonGroup.Title = 'Medium';
            app.WaterButton = uiradiobutton(app.MediumButtonGroup);
            app.WaterButton.Text = 'Water';
            app.WaterButton.Value = true;
            app.AirButton = uiradiobutton(app.MediumButtonGroup);
            app.AirButton.Text = 'Air';
            app.ReynoldsNumberEditFieldLabel = uilabel(app.InputdataPanel);
            app.ReynoldsNumberEditFieldLabel.Text = 'Reynolds Number';
            app.ReynoldsNumberEditField = uieditfield(app.InputdataPanel, 'numeric');
            app.CykloneDataPathEditFieldLabel = uilabel(app.InputdataPanel);
            app.CykloneDataPathEditFieldLabel.Text = 'CykloneDataPath';
            app.CykloneDataPathEditField = uieditfield(app.InputdataPanel, 'text');
            app.ProfilePowerCurvesComparerLabel = uilabel(app.UIFigure);
            app.ProfilePowerCurvesComparerLabel.Text = 'Profile Power Curves Comparer';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guiApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end